classdef BajaStatsV1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CompetitionResultsTab           matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        YearDropDownLabel               matlab.ui.control.Label
        YearDropDown                    matlab.ui.control.DropDown
        CompetitionDropDownLabel        matlab.ui.control.Label
        CompetitionDropDown             matlab.ui.control.DropDown
        StandingsTabulationButtonGroup  matlab.ui.container.ButtonGroup
        SingleCompetitionButton         matlab.ui.control.RadioButton
        IronTeamButton                  matlab.ui.control.RadioButton
        SeasonAverageButton             matlab.ui.control.RadioButton
        EventDropDownLabel              matlab.ui.control.Label
        EventDropDown                   matlab.ui.control.DropDown
        Label                           matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        ResultsCorrelationTab           matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % loading results from .mat file
            allResults = struct2cell(load('Results_Tables_Updated.mat'))
            
            % fixing results in one table
            VarName17 = zeros(120, 1);
            allResults{8} = [allResults{8}(:, 1:16), array2table(VarName17), allResults{8}(:, 17:18)];
            
            % arrays for storing which events occured at which competitions
            sledPull = [23 25 27 29 30 32 34 35 38 41 44 50 53 56 58];
            salesPres = [22 26 28 31 36 37 40 43:60];
            techEvent = [28 29 30];
            techPen = [25 26 27];
            techPenEnd = [22 23 24];
            waterManeu = [23 27 33 35];
            rockCrawl = [22 26 28 31 36 37 40 45 46 54 57]; %comes after hill climb in dataset, regular s&t comes before
            mudBog = [23 29 38];
            
            % filling row with number associated with each comp(first comp of 2000 is 1)
            for i = 1:size(allResults, 1)
                allResults{i, 2} = 21 + i;
            end
            
            % array storing names of each comp
            compNames = ["SDSM 2007", "UCF 2007", "RIT 2007", "Montreal 2008", "Illinois 2008", "Tennessee 2008", "Oregon 2009", "Wisconsin 2009",  "Alabama 2009", "Washington 2010", "Carolina 2010", "Rochester 2010", "Kansas 2011", "Birmingham 2011", "Illinois 2011", "Oregon 2012", "Wisconsin 2012", "Auburn 2012", "Washington 2013", "Tennessee 2013", "Rochester 2013", "UTEP 2014", "Kansas 2014","Illinois 2014", "Oregon 2015", "Auburn 2015", "Maryland 2015", "California 2016", "Tennessee 2016", "Rochester 2016", "California 2017", "Kansas 2017", "Illinois 2017", "Maryland 2018", "Kansas 2018", "Oregon 2018", "Tennessee 2019", "California 2019", "Rochester 2019"]
            
            % adding competition names to cell array
            for i = 1:size(compNames, 2)
                allResults{i, 3} = compNames(i);
            end
            
            %for loops for naming columns. fills based on how SAE seems to fill the
            %tables. competition 23 doesn't follow the pattern though
            for i = 1:size(allResults, 1)
                if allResults{i, 2} ~= 23
                    offset = 0;
                    
                    allResults{i, 1}.Properties.VariableNames([1:9]) = {'overallRank' 'carNumber' 'universityName' 'teamName' 'overallScore' 'overallDynamic' 'overallStatic' 'costScore' 'designScore'};
                    
                    if ismember(allResults{i, 2}, salesPres)
                        allResults{i, 1}.Properties.VariableNames(10 + offset) = {'salesScore'};
                        offset = offset + 1;
                    end
                    
                    if ismember(allResults{i, 2}, techPen)
                        allResults{i, 1}.Properties.VariableNames(10 + offset) = {'techPenalty'};
                        offset = offset + 1;
                    end
                    
                    if ismember(allResults{i, 2}, techEvent)
                        allResults{i, 1}.Properties.VariableNames(10 + offset) = {'techScore'};
                        offset = offset + 1;
                    end
                    
                    allResults{i, 1}.Properties.VariableNames([10 + offset, 11 + offset]) = {'accelScore', 'landManeuScore'};
                    
                    if ismember(allResults{i, 2}, mudBog)
                        allResults{i, 1}.Properties.VariableNames(12 + offset) = {'mudBogScore'};
                        offset = offset + 1;
                    end
                    
                    if ismember(allResults{i, 2}, rockCrawl)
                        allResults{i, 1}.Properties.VariableNames(12 + offset) = {'rockCrawlScore'};
                        offset = offset + 1;
                    end
                    
                    if ismember(allResults{i, 2}, sledPull)
                        allResults{i, 1}.Properties.VariableNames(12 + offset) = {'sledPullScore'};
                    else
                        allResults{i, 1}.Properties.VariableNames(12 + offset) = {'hillClimbScore'};
                    end
                    
                    if ~ismember(allResults{i, 2}, rockCrawl)
                        allResults{i, 1}.Properties.VariableNames(13 + offset) = {'susTracScore'};
                        offset = offset + 1;
                    end
                    
                    if ismember(allResults{i, 2}, waterManeu)
                        allResults{i, 1}.Properties.VariableNames(13 + offset) = {'waterManeuScore'};
                        offset = offset + 1;
                    end
                    
                    allResults{i, 1}.Properties.VariableNames(13 + offset) = {'enduroScore'};
                    
                    if ismember(allResults{i, 2}, techPenEnd)
                        allResults{i, 1}.Properties.VariableNames(14 + offset) = {'techPenalty'};
                        offset = offset + 1;
                    end
                    
                    allResults{i, 1}.Properties.VariableNames(14 + offset) = {'penalty'};
                    
                    if ~ismember(allResults{i, 2}, techPen) && ~ismember(allResults{i, 2}, techPenEnd)
                        allResults{i, 1}.Properties.VariableNames(15 + offset) = {'registered'};
                        allResults{i, 1}.Properties.VariableNames(16 + offset) = {'techPassed'};
                    elseif ismember(allResults{i, 2}, techPen)
                        allResults{i, 1}.Properties.VariableNames(15 + offset) = {'registered'};
                    end
                else
                    allResults{i, 1}.Properties.VariableNames = {'overallRank' 'carNumber' 'universityName' 'teamName' 'overallScore' 'designScore' 'costScore' 'accelScore' 'mudBogScore' 'susTracScore' 'waterManeuScore' 'sledPullScore' 'enduroScore' 'techPenalty' 'penalty'};
                end
                
                allResults{i, 1}.universityName = convertCharsToStrings(allResults{i, 1}.universityName);
            end
            
            % Paired list of names that refer to same uni
            
            wrongNames = ["Christian Brothers College" "Arizona State Univ - Polytechnic" "California Polytechnic State Univ-SLO" "City College of the City Univ of NY";
                "Christian Brothers Univ" "Arizona State Univ Polytechnic" "California State Poly Univ-San Luis Obis" "City College of New York"];
            
            % List of all universities that have participated in Baja SAE competitions
            
            allUnis = getParticipatingUnis(allResults, wrongNames);
            
            % Add data to the Table UI Component
            app.UITable.Data = allResults{39, 1};
            setappdata(app.UITable,'allResults',allResults)
            
            set(app.UITable, 'columnname', allResults{39, 1}.Properties.VariableNames);
            app.EventDropDown.Items = allResults{39, 1}.Properties.VariableNames
            
            app.Label.Text = allResults{39, 3};
        end

        % Value changed function: EventDropDown
        function EventDropDownValueChanged(app, event)
            value = app.EventDropDown.Value
            
            allResults = getappdata(app.UITable,'allResults');
            
            yearsPassed = str2num(app.YearDropDown.Value) - 2007;
            compNum = str2num(app.CompetitionDropDown.Value);
            
            
            data = allResults{3*yearsPassed + compNum, 1}.(value)
            
            %% Plot new data
            if(strcmp(class(data), 'double'))
                histogram(app.UIAxes, data, 10);
                xlabel(app.UIAxes,'Score') 
            else
                histogram(app.UIAxes, data);
                xlabel(app.UIAxes,'Category') 
            end
            
            
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            value = app.YearDropDown.Value;
            allResults = getappdata(app.UITable,'allResults');
            
            yearsPassed = str2num(value) - 2007;
            
            compNum = str2num(app.CompetitionDropDown.Value);
            
            % Add data to the Table UI Component
            app.UITable.Data = allResults{3*yearsPassed + compNum, 1};
            
            set(app.UITable, 'columnname', allResults{3*yearsPassed + compNum, 1}.Properties.VariableNames);
            app.EventDropDown.Items = allResults{3*yearsPassed + compNum, 1}.Properties.VariableNames
            
            app.Label.Text = allResults{3*yearsPassed + compNum, 3};
            
            data = allResults{3*yearsPassed + compNum, 1}.(app.EventDropDown.Value)
            
            %% Plot new data
            if(strcmp(class(data), 'double'))
                histogram(app.UIAxes, data, 10);
                xlabel(app.UIAxes,'Score') 
            else
                histogram(app.UIAxes, data);
                xlabel(app.UIAxes,'Category') 
            end
        end

        % Value changed function: CompetitionDropDown
        function CompetitionDropDownValueChanged(app, event)
            value = app.CompetitionDropDown.Value;
            
            allResults = getappdata(app.UITable,'allResults')
            
            yearsPassed = str2num(app.YearDropDown.Value) - 2007
            
            compNum = str2num(value)
            
            % Add data to the Table UI Component
            app.UITable.Data = allResults{3*yearsPassed + compNum, 1};
            
            set(app.UITable, 'columnname', allResults{3*yearsPassed + compNum, 1}.Properties.VariableNames);
            
            app.EventDropDown.Items = allResults{3*yearsPassed + compNum, 1}.Properties.VariableNames
            app.Label.Text = allResults{3*yearsPassed + compNum, 3};
            
            data = allResults{3*yearsPassed + compNum, 1}.(app.EventDropDown.Value)
            
            %% Plot new data
            if(strcmp(class(data), 'double'))
                histogram(app.UIAxes, data, 10);
                xlabel(app.UIAxes,'Score') 
            else
                histogram(app.UIAxes, data);
                xlabel(app.UIAxes,'Category') 
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CompetitionResultsTab = uitab(app.TabGroup);
            app.CompetitionResultsTab.Title = 'Competition Results';
            app.UITable = uitable(app.CompetitionResultsTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.YearDropDownLabel = uilabel(app.CompetitionResultsTab);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.CompetitionResultsTab);
            app.YearDropDown.Items = {'2007', '2008', '2009', '2010', '2011', '2012', '2013', '2014', '2015', '2016', '2017', '2018', '2019'};
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.YearDropDown.Value = '2019';
            app.CompetitionDropDownLabel = uilabel(app.CompetitionResultsTab);
            app.CompetitionDropDownLabel.Text = 'Competition';
            app.CompetitionDropDown = uidropdown(app.CompetitionResultsTab);
            app.CompetitionDropDown.Items = {'1', '2', '3'};
            app.CompetitionDropDown.ValueChangedFcn = createCallbackFcn(app, @CompetitionDropDownValueChanged, true);
            app.CompetitionDropDown.Value = '3';
            app.StandingsTabulationButtonGroup = uibuttongroup(app.CompetitionResultsTab);
            app.StandingsTabulationButtonGroup.Tooltip = {'Single Competition: Results for just the competition selected'; ''; 'Iron Team: Summed results across all three competitions in a season'; ''; 'Season Average: Average result in each competition'};
            app.StandingsTabulationButtonGroup.Title = 'Standings Tabulation';
            app.SingleCompetitionButton = uiradiobutton(app.StandingsTabulationButtonGroup);
            app.SingleCompetitionButton.Text = 'Single Competition';
            app.SingleCompetitionButton.Value = true;
            app.IronTeamButton = uiradiobutton(app.StandingsTabulationButtonGroup);
            app.IronTeamButton.Text = 'Iron Team';
            app.SeasonAverageButton = uiradiobutton(app.StandingsTabulationButtonGroup);
            app.SeasonAverageButton.Text = 'Season Average';
            app.EventDropDownLabel = uilabel(app.CompetitionResultsTab);
            app.EventDropDownLabel.Text = 'Event';
            app.EventDropDown = uidropdown(app.CompetitionResultsTab);
            app.EventDropDown.Items = {'overallScore', 'designScore', 'costScore', 'accelScore', 'mudBogScore', 'susTracScore', 'waterManeuScoresledPullScore', 'enduroScore'};
            app.EventDropDown.ValueChangedFcn = createCallbackFcn(app, @EventDropDownValueChanged, true);
            app.EventDropDown.Value = 'overallScore';
            app.Label = uilabel(app.CompetitionResultsTab);
            app.UIAxes = uiaxes(app.CompetitionResultsTab);
            title(app.UIAxes, 'Distribution')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ResultsCorrelationTab = uitab(app.TabGroup);
            app.ResultsCorrelationTab.Title = 'Results Correlation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BajaStatsV1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end